classdef score < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        InitialPageButton    matlab.ui.control.Button
        Label                matlab.ui.control.Label
        Image_2              matlab.ui.control.Image
        Image                matlab.ui.control.Image
        CheckyourscoreLabel  matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            run('value_teste.m');
            value = evalin("base",'val');
            app.Label.Text = num2str(value);
        end

        % Button pushed function: InitialPageButton
        function InitialPageButtonPushed(app, event)
            inicioWindow = inicio;
            inicioFig = inicioWindow.UIFigure; 

            app.UIFigure.UserData.inicioFig = inicioFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';
        end

        % Callback function: not associated with a component
        function ButtonPushed(app, event)
            run('value_teste.m');
            value = evalin("base",'val');
            app.Label.Text = num2str(value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckyourscoreLabel = uilabel(app.UIFigure);
            app.CheckyourscoreLabel.Text = 'Check your score!';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo1.png');
            app.Image_2 = uiimage(app.UIFigure);
            app.Image_2.ImageSource = fullfile(pathToMLAPP, 'green-glowing-circle-icon-13.png');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '0';
            app.InitialPageButton = uibutton(app.UIFigure, 'push');
            app.InitialPageButton.ButtonPushedFcn = createCallbackFcn(app, @InitialPageButtonPushed, true);
            app.InitialPageButton.Text = 'Initial Page';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = score

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end