classdef video < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        MeditationoclockLabel  matlab.ui.control.Label
        Image                  matlab.ui.control.Image
        UIAxes                 matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        AudioPlayer audioplayer
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Add the necessary costum and EEGLab functions 
            addpath 'D:\Neuro\Projeto\MindSpark\functions\sigprocfunc'
            addpath 'D:\Neuro\Projeto\MindSpark'

            % Load and show the meditation image
            axes_handle = app.UIAxes;
            image_file = 'meditação.png';
            imshow(image_file, 'Parent', axes_handle);
            drawnow
            
            % Connect the Muse Headband
            mu = MuseUdp();
            
            % Set the Sampling Frequency and Epoch duration
            epoch_length = 30;
            Fs = 256;
            
            % Initialize the variables
            vector_tp9 = [];
            vector_af7 = [];
            vector_af8 = [];
            vector_tp10 = [];

            EalphaGamma_tp = [];
            EalphaBetaASM_tp = [];
            EEbeta_tp = [];
            EEgamma_tp = [];
            EEtheta_tp = [];
            DASMalpha_tp = [];
            PSD_alpha_tp = [];
            
            EalphaGamma_af = [];
            EalphaBetaASM_af = [];
            EEbeta_af = [];
            EEgamma_af = [];
            EEtheta_af = [];
            DASMalpha_af = [];
            PSD_alpha_af = [];
            
            alpha_freq = [8, 13];       
            theta_freq = [4, 8];
            beta_freq = [14, 30];
            gamma_freq = [30; 50];
            
            % Load the runtime Selected by the user in the previous page
            runtime = evalin('base', 'runtime');

            % Start playing the relaxing music
            audio_player = audioplayer(audioread('audio_maior.mp3'), 44100);
            play(audio_player);

            % Acquisition loop using tic and toc MatLab functionalities
            tic
            while toc<runtime
                play(audio_player);
                % Get a eeg sample in each instant
                [data, ~, success] = mu.get_eeg_sample();
                % If data was obtained then store it
                if success
                    vector_tp9(end+1) = data(1);
                    vector_af7(end+1) = data(2);
                    vector_af8(end+1) = data(3);
                    vector_tp10(end+1) = data(4);
                end
            end
            
            % Determine the virtual polar channels by computing the average
            signal_tp = (vector_tp9 + vector_tp10) ./2;
            signal_af = (vector_af8 + vector_af7) ./2;
            
            % Decompose each signal into alpha, theta, beta and gamma bands
            [alpha_signaltp, theta_signaltp, beta_signaltp, gamma_signaltp] = signal_decompose(signal_tp, Fs);
            [alpha_signalaf, theta_signalaf, beta_signalaf, gamma_signalaf] = signal_decompose(signal_af, Fs);
            
            % Compute the features for AF and TP channels
            EalphaGamma_af = (signal_energy(alpha_signalaf, epoch_length, Fs)./signal_energy(gamma_signalaf, epoch_length, Fs));
            EalphaBetaASM_af = (signal_energy(alpha_signalaf, epoch_length, Fs)./asm_signal(vector_af7,vector_af8,epoch_length, Fs));
            EEbeta_af = (energy_entropy(beta_signalaf, epoch_length, Fs));
            EEgamma_af = (energy_entropy(gamma_signalaf, epoch_length, Fs));
            EEtheta_af = (energy_entropy(theta_signalaf, epoch_length,Fs));
            DASMalpha_af = (dasm_signal(vector_af7, vector_af8, epoch_length, Fs));
            PSD_beta_af = (psd_signal(beta_signalaf, epoch_length, Fs, alpha_freq));
            
            EalphaGamma_tp = (signal_energy(alpha_signaltp, epoch_length, Fs)./signal_energy(gamma_signaltp, epoch_length, Fs));
            EalphaBetaASM_tp = (signal_energy(alpha_signaltp, epoch_length, Fs)./asm_signal(vector_tp9,vector_tp10,epoch_length, Fs));
            EEbeta_tp = (energy_entropy(beta_signaltp, epoch_length, Fs));
            EEgamma_tp = (energy_entropy(gamma_signaltp, epoch_length, Fs));
            EEtheta_tp = (energy_entropy(theta_signaltp, epoch_length,Fs));
            DASMalpha_tp = (dasm_signal(vector_tp9, vector_tp10, epoch_length, Fs));
            PSD_beta_tp = (psd_signal(beta_signaltp, epoch_length, Fs, alpha_freq));
            
            % Load the pre-trained KNN model
            modelFile = "D:\Neuro\Projeto\MindSpark\creating dataset\bestModelBoth.mat";
            load(modelFile,'bestClassifier','mu','sigma');
            
            % Use the previous features to predict the person state
            features = [EalphaGamma_af' EalphaBetaASM_af' EEbeta_af' EEgamma_af' EEtheta_af' DASMalpha_af' PSD_beta_af' EalphaGamma_tp' EalphaBetaASM_tp' EEbeta_tp' EEgamma_tp' EEtheta_tp' DASMalpha_tp' PSD_beta_tp'];
            features = (features-mu)./sigma;
            [~, scores, ~] = predict(bestClassifier, features);
            
            % Using the nearest neighbours, compute the final score by
            % averaging the score in each epoch
            val = floor(mean(scores(:,1)) * 100);

            % Assign the final score and epochs score so that they can be
            % accessed by the following pages
            assignin('base', 'scores', scores(:,1).*100);
            assignin('base', 'val', val);
            
            % Stop the audio
            stop(audio_player);
            delete(audio_player)
            
            % Activate the next window
            scoreWindow = score;
            scoreFig = scoreWindow.UIFigure; 

            app.UIFigure.UserData.scoreFig = scoreFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';
        end

        % Callback function
        function BackButtonPushed(app, event)
            inicioWindow = inicio;
            inicioFig = inicioWindow.UIFigure; 

            app.UIFigure.UserData.inicioFig = inicioFig;

            % Hide the main window
            app.UIFigure.Visible = 'off';
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'logo2.png');
            app.MeditationoclockLabel = uilabel(app.UIFigure);
            app.MeditationoclockLabel.Text = 'Meditation  o''clock!';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = video

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end